classdef MetalDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetalDetectionUIFigure  matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        Menu_2                  matlab.ui.container.Menu
        Panel_Result            matlab.ui.container.Panel
        UIAxes_Scene            matlab.ui.control.UIAxes
        UIAxes_Image            matlab.ui.control.UIAxes
        Panel_Scene             matlab.ui.container.Panel
        Panel_Model             matlab.ui.container.Panel
        Panel_Detector          matlab.ui.container.Panel
        AEditFieldLabel         matlab.ui.control.Label
        AEditField              matlab.ui.control.NumericEditField
        mEditFieldLabel         matlab.ui.control.Label
        mEditField              matlab.ui.control.NumericEditField
        ButtonGroup             matlab.ui.container.ButtonGroup
        FD                      matlab.ui.control.RadioButton
        TD                      matlab.ui.control.RadioButton
        HzEditFieldLabel        matlab.ui.control.Label
        HzEditField             matlab.ui.control.NumericEditField
        Panel_Space             matlab.ui.container.Panel
        xLabel                  matlab.ui.control.Label
        xEditField              matlab.ui.control.NumericEditField
        xEditField_2            matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        mLabel                  matlab.ui.control.Label
        yEditFieldLabel         matlab.ui.control.Label
        yEditField              matlab.ui.control.NumericEditField
        xEditField_4            matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        mLabel_2                matlab.ui.control.Label
        zEditFieldLabel         matlab.ui.control.Label
        zEditField              matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        xEditField_5            matlab.ui.control.NumericEditField
        mLabel_3                matlab.ui.control.Label
        Panel_Metal             matlab.ui.container.Panel
        smEditFieldLabel        matlab.ui.control.Label
        smEditField             matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        xyzmEditFieldLabel      matlab.ui.control.Label
        xyzmEditField           matlab.ui.control.EditField
        Label_7                 matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        EditField_6Label        matlab.ui.control.Label
        EditField_6             matlab.ui.control.NumericEditField
        mLabel_4                matlab.ui.control.Label
        mEditField_3            matlab.ui.control.NumericEditField
        mEditField_4Label       matlab.ui.control.Label
        mEditField_4            matlab.ui.control.NumericEditField
        Panel_Algorithm         matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        Tab_Image               matlab.ui.container.Tab
        Tab_Inversion           matlab.ui.container.Tab
        Tab_3                   matlab.ui.container.Tab
        Panel_Data              matlab.ui.container.Panel
        mEditField_5Label       matlab.ui.control.Label
        mEditField_5            matlab.ui.control.NumericEditField
        mLabel_5                matlab.ui.control.Label
        mEditField_6            matlab.ui.control.NumericEditField
        mLabel_6                matlab.ui.control.Label
        mEditField_7            matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        DropDown_Bxyz           matlab.ui.control.DropDown
        Label_9                 matlab.ui.control.Label
        DropDown_Feature        matlab.ui.control.DropDown
        Button_3                matlab.ui.control.Button
    end


    methods (Access = private)
    
        function results = Initialize(app)
            addpath(genpath('../0电磁模型/matlab'));
            addpath(genpath('../2探测定位'));
            addpath(genpath('../3模型反演'));
            %% Global parameter
           
            global Scene;   % 场景
            Scene=[];
            model=struct(); % 模型参数
            Space=struct();      % 空间参数
            Space.xRange=[app.xEditField.Value app.xEditField_2.Value];
            Space.yRange=[app.yEditField.Value app.xEditField_4.Value];
            Space.zRange=[app.zEditField.Value app.xEditField_5.Value];
            model.space=Space;
            detector=struct();  % 探测器
            detector.I=app.AEditField.Value;
            detector.R=app.mEditField.Value;
            detector.F=app.HzEditField.Value;
            if app.FD.Value
                detector.type="FD";
            else
                detector.type="TD";
            end
            model.detector=detector;
            metal=struct();     %金属目标
            metal.postion=double(string(split(app.xyzmEditField.Value)));
            posture=double(string(split(app.EditField.Value)));
            if string(app.DropDown.Value)=="极化率因子"
                    metal.mx=posture(1);
                    metal.my=posture(2);
                    metal.mz=posture(3);
                    metal.theta=posture(1);
                    metal.phi=posture(2);
                    metal.psi=posture(3);
            elseif(string(app.DropDown.Value)=="球体")
                    metal.theta=posture(1);
                    metal.phi=posture(2);
                    metal.psi=posture(3);
            elseif(string(app.DropDown.Value)=="圆柱体")
                  metal.theta=posture(1);
                  metal.phi=posture(2);
                  metal.psi=posture(3);
            else 
                error('金属目标类型未定义');
            end
            metal.type=string(app.DropDown.Value); 
            metal.R=app.mEditField_3.Value;
            metal.L=app.mEditField_4.Value;          
            metal.E=app.smEditField.Value;
            metal.U=app.EditField_6.Value;
            model.metal=metal;
            Scene.model=model;
            
            
            dataconf=struct();%%  数据参数
            dataconf.linespace=app.mEditField_5.Value;
            dataconf.interval=app.mEditField_6.Value;
            dataconf.height=app.mEditField_7.Value;
            dataconf.Bxyz=app.DropDown_Bxyz.Value;
            dataconf.feature=app.DropDown_Feature.Value;
            dataconf.v_x=Space.xRange(1):dataconf.linespace:Space.xRange(2);
            dataconf.v_y=Space.yRange(1):dataconf.interval:Space.yRange(2);
            [dataconf.m_x,dataconf.m_y]=meshgrid(dataconf.v_x,dataconf.v_y);        % 观测面扫描网格
            Scene.dataconf=dataconf;
            
            algorithm=struct();%% 算法参数
            
            Scene.algorithm=algorithm;
            CaluateField(app);
           
            results=1;
            
        end
        
        function results = ShowScene(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            % Scene
            hold(app.UIAxes_Scene,'off'); 
            plot3(app.UIAxes_Scene,Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.dataconf.height*ones(size(Scene.dataconf.m_y)),'k');
            set(app.UIAxes_Scene,'ZLim',Scene.model.space.zRange);
            hold(app.UIAxes_Scene,'on'); 
            plot3(app.UIAxes_Scene,Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.dataconf.height*ones(size(Scene.dataconf.m_y)),'or');
                % geneate metal object cylinder or sphere
            if Scene.model.metal.type=="球体"
                
            elseif Scene.model.metal.type=="圆柱体"
                r=Scene.model.metal.R;
                h=Scene.model.metal.L;
                x=Scene.model.metal.postion(1);
                y=Scene.model.metal.postion(2);
                z=Scene.model.metal.postion(3);
                N=20;
                [X,Y,Z]=cylinder(r,N);
                Z=Z-0.5;
                Z=h*Z/2;
                Rt=RotationTensor(Scene.model.metal.theta*pi/180,Scene.model.metal.phi*pi/180,Scene.model.metal.psi*pi/180);
                XX=X(2,: );
                YY=Y(2,: );
                Z1=Z(1,: );
                Z2=Z(2,: );
                for i=1:N+1
                    r0=[X(1,i),Y(1,i),Z(1,i)]';
                    r1=Rt*r0;
                    XC(1,i)=r1(1);YC(1,i)=r1(2);ZC(1,i)=r1(3);
                    r0=[X(2,i),Y(2,i),Z(2,i)]';
                    r1=Rt*r0;
                    XC(2,i)=r1(1);YC(2,i)=r1(2);ZC(2,i)=r1(3);
                    
                    r0=[XX(i),YY(i),Z1(i)]';
                    rT=Rt*r0;
                    XX1C(i)=rT(1);YY1C(i)=rT(2);Z1C(i)=rT(3);
                    r=[XX(i),YY(i),Z2(i)]';
                    rT=Rt*r;
                    XX2C(i)=rT(1);YY2C(i)=rT(2);Z2C(i)=rT(3);
                     
                    
                end
                copper=[231 194 97]/255;
                fill3(app.UIAxes_Scene,XX1C+x,YY1C+y,Z1C+z,copper,'EdgeColor','none');
               
                fill3(app.UIAxes_Scene,XX2C+x,YY2C+y,Z2C+z,copper,'EdgeColor','none');

                XC=XC+x;YC=YC+y;ZC=ZC+z;
                surf(app.UIAxes_Scene,XC,YC,ZC,'FaceColor',copper,'EdgeColor','none');
                camlight(app.UIAxes_Scene); 
                lighting(app.UIAxes_Scene,'gouraud');
                axis(app.UIAxes_Image,'equal');
            elseif Scene.model.metal.type=="极化率因子"
                
            end
            xlabel(app.UIAxes_Scene,'x/m');
            ylabel(app.UIAxes_Scene,'y/m');
            zlabel(app.UIAxes_Scene,'z/m');
            title(app.UIAxes_Scene,'探测场景');
            % Feature map
            colorbar(app.UIAxes_Image,'off');
            hold(app.UIAxes_Image,'off'); 
            contourf(app.UIAxes_Image,Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.result.Bxyz,6);
            xlabel(app.UIAxes_Image,'x/m');
            ylabel(app.UIAxes_Image,'y/m');
            title(app.UIAxes_Image,'扫描图');
            colormap(app.UIAxes_Image,parula);
            colorbar(app.UIAxes_Image);
            axis(app.UIAxes_Image,'equal');
            
            % other image
%             figure('name','3-D figure of orignal data')
%             surf(Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.result.Bxyz);
%             title('orignal data');
%             xlabel("x/m");
%             ylabel("y/m");
%             zlabel("z/m");

            
        end
        
        function results = CaluateField(app)
              global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            k=0;
            Hx=zeros(size(Scene.dataconf.m_x));
            Hy=zeros(size(Scene.dataconf.m_x));
            Hz=zeros(size(Scene.dataconf.m_x));
            if Scene.model.metal.type=="极化率因子"
                for i=1:length(Scene.dataconf.v_y)          % 对二维区域的单元格进行扫描，相对于移动线圈
                    for j=1:length(Scene.dataconf.v_y)
                        k=k+1;
                        x=Scene.dataconf.v_x(j);            % 当前线圈的x坐标
                        y=Scene.dataconf.v_y(i);            % 当前线圈的y坐标
                        Coil=struct();
                        Coil.Postion=[x y Scene.dataconf.height];  % 线圈位置，默认高度为0保持不变   
                        Coil.I=Scene.model.detector.I;
                        Coil.R=Scene.model.detector.R;
                        Coil.f=Scene.model.detector.F;
                        Target=struct();
                        Target.Postion=Scene.model.metal.postion;
                        Target.MagPolar=[ Scene.model.metal.mx  Scene.model.metal.my  Scene.model.metal.mz]*10^7;
                        Target.Theta=Scene.model.metal.theta;
                        Target.Phi=Scene.model.metal.phi;
                        Target.Psi=Scene.model.metal.psi;
                        [Hx(i,j),Hy(i,j),Hz(i,j)]=SecondField(Coil,Target,struct('Postion', Coil.Postion));           % 计算当前位置二次场  
                    end
                end
            elseif Scene.model.metal.type=="球体"
                for i=1:length(Scene.dataconf.v_y)          % 对二维区域的单元格进行扫描，相对于移动线圈
                    for j=1:length(Scene.dataconf.v_x)
                        x=Scene.dataconf.v_x(j);            % 当前线圈的x坐标
                        y=Scene.dataconf.v_y(i);            % 当前线圈的y坐标
                        CoilPostion=[x y Scene.dataconf.height]; % 线圈位置，默认高度为0   
                        h=Scene.dataconf.height;
                        I=Scene.model.detector.I;
                        R=Scene.model.detector.R;
                        f=Scene.model.detector.F;
                        SphereR=Scene.model.metal.R;
                        u=Scene.model.metal.U*10^7;
                        sigma=Scene.model.metal.E;
                        postion=Scene.model.metal.postion';
                        SphereMxyz=MomSphere(FirstField(I,R,f,(postion-CoilPostion)),u,sigma,f,SphereR);  % 计算磁矩，SpherePostion-CoilPostion即物体相对于线圈的位置
                        SpereMM=[postion(:) SphereMxyz(:)];                                               % 定义磁矩参数
                        [Hx(i,j),Hy(i,j),Hz(i,j)]=HFieldModel(SpereMM,x,y,h);             % 计算当前位置二次场
                        
                    end
                end
            elseif Scene.model.metal.type=="圆柱体"
                for i=1:length(Scene.dataconf.v_y)          % 对二维区域的单元格进行扫描，相对于移动线圈
                    for j=1:length(Scene.dataconf.v_x)
                        x=Scene.dataconf.v_x(j);            % 当前线圈的x坐标
                        y=Scene.dataconf.v_y(i);            % 当前线圈的y坐标
                        CoilPostion=[x y Scene.dataconf.height]; % 线圈位置，默认高度为0   
                        h=Scene.dataconf.height;
                        I=Scene.model.detector.I;
                        R=Scene.model.detector.R;
                        f=Scene.model.detector.F;
                        CR=Scene.model.metal.R;
                        p=Scene.model.metal.L/(2*CR);
                        Cu=Scene.model.metal.U*10^7;
                        Csigma=Scene.model.metal.E;
                        postion=Scene.model.metal.postion';
                         
                        theta=Scene.model.metal.theta*pi/180;
                        phi=Scene.model.metal.phi*pi/180;
      
                        CMxyz=MomCylinder(FirstField(I,R,f,(postion-CoilPostion)),Cu,Csigma,f,p,theta,phi,CR);
                        CMM=[postion(:) CMxyz(:)];
                        [Hx(i,j),Hy(i,j),Hz(i,j)]=HFieldModel(CMM,x,y,h);             % 计算当前位置二次场
                   
                    end
                end
            end
            if Scene.dataconf.feature=="幅度"
               Hx=abs(Hx);
               Hy=abs(Hy);
               Hz=abs(Hz);
            elseif Scene.dataconf.feature=="相位"
               Hx=abs(Hx);
               Hy=abs(Hy);
               Hz=abs(Hz);           
            end
            if Scene.dataconf.Bxyz=="总场"
                Scene.result.Bxyz=sqrt(Hx.^2+Hy.^2+Hz.^2);
            elseif Scene.dataconf.Bxyz=="Bx"
                Scene.result.Bxyz=Hx;
            elseif Scene.dataconf.Bxyz=="By"
                Scene.result.Bxyz=Hy;
            elseif Scene.dataconf.Bxyz=="Bz"
                Scene.result.Bxyz=Hz;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Initialize(app);
             ShowScene(app);

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Initialize(app);
            CaluateField(app);
            ShowScene(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetalDetectionUIFigure = uifigure;
            app.MetalDetectionUIFigure.Name = 'MetalDetection';
            app.Menu = uimenu(app.MetalDetectionUIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.MetalDetectionUIFigure);
            app.Menu_2.Text = '配置';
            app.Panel_Result = uipanel(app.MetalDetectionUIFigure);
            app.Panel_Result.Title = '结果';
            app.UIAxes_Scene = uiaxes(app.Panel_Result);
            title(app.UIAxes_Scene, 'Title')
            xlabel(app.UIAxes_Scene, 'X')
            ylabel(app.UIAxes_Scene, 'Y')
            app.UIAxes_Image = uiaxes(app.Panel_Result);
            title(app.UIAxes_Image, 'Title')
            xlabel(app.UIAxes_Image, 'X')
            ylabel(app.UIAxes_Image, 'Y')
            app.Panel_Scene = uipanel(app.MetalDetectionUIFigure);
            app.Panel_Scene.Title = '场景配置';
            app.Panel_Model = uipanel(app.Panel_Scene);
            app.Panel_Model.Title = '模型';
            app.Panel_Detector = uipanel(app.Panel_Model);
            app.Panel_Detector.Title = '探测器配置';
            app.AEditFieldLabel = uilabel(app.Panel_Detector);
            app.AEditFieldLabel.Text = '电流/A';
            app.AEditField = uieditfield(app.Panel_Detector, 'numeric');
            app.AEditField.Value = 20;
            app.mEditFieldLabel = uilabel(app.Panel_Detector);
            app.mEditFieldLabel.Text = '半径/m';
            app.mEditField = uieditfield(app.Panel_Detector, 'numeric');
            app.mEditField.Value = 0.25;
            app.ButtonGroup = uibuttongroup(app.Panel_Detector);
            app.ButtonGroup.Title = '类型';
            app.FD = uiradiobutton(app.ButtonGroup);
            app.FD.Text = '频域';
            app.FD.Value = true;
            app.TD = uiradiobutton(app.ButtonGroup);
            app.TD.Text = '时域';
            app.HzEditFieldLabel = uilabel(app.Panel_Detector);
            app.HzEditFieldLabel.Text = '频率/Hz';
            app.HzEditField = uieditfield(app.Panel_Detector, 'numeric');
            app.HzEditField.Value = 1000;
            app.Panel_Space = uipanel(app.Panel_Model);
            app.Panel_Space.Title = '空间定义';
            app.xLabel = uilabel(app.Panel_Space);
            app.xLabel.Text = 'x:';
            app.xEditField = uieditfield(app.Panel_Space, 'numeric');
            app.xEditField.Value = -2;
            app.xEditField_2 = uieditfield(app.Panel_Space, 'numeric');
            app.xEditField_2.Value = 2;
            app.Label_2 = uilabel(app.Panel_Space);
            app.Label_2.Text = '~';
            app.mLabel = uilabel(app.Panel_Space);
            app.mLabel.Text = 'm';
            app.yEditFieldLabel = uilabel(app.Panel_Space);
            app.yEditFieldLabel.Text = 'y:';
            app.yEditField = uieditfield(app.Panel_Space, 'numeric');
            app.yEditField.Value = -2;
            app.xEditField_4 = uieditfield(app.Panel_Space, 'numeric');
            app.xEditField_4.Value = 2;
            app.Label_4 = uilabel(app.Panel_Space);
            app.Label_4.Text = '~';
            app.mLabel_2 = uilabel(app.Panel_Space);
            app.mLabel_2.Text = 'm';
            app.zEditFieldLabel = uilabel(app.Panel_Space);
            app.zEditFieldLabel.Text = 'z:';
            app.zEditField = uieditfield(app.Panel_Space, 'numeric');
            app.zEditField.Value = -2;
            app.Label_5 = uilabel(app.Panel_Space);
            app.Label_5.Text = '~';
            app.xEditField_5 = uieditfield(app.Panel_Space, 'numeric');
            app.mLabel_3 = uilabel(app.Panel_Space);
            app.mLabel_3.Text = 'm';
            app.Panel_Metal = uipanel(app.Panel_Model);
            app.Panel_Metal.Title = '目标物体';
            app.smEditFieldLabel = uilabel(app.Panel_Metal);
            app.smEditFieldLabel.Text = '电导率(s/m)';
            app.smEditField = uieditfield(app.Panel_Metal, 'numeric');
            app.smEditField.Value = 5.17;
            app.Label_6 = uilabel(app.Panel_Metal);
            app.Label_6.Text = '类型';
            app.DropDown = uidropdown(app.Panel_Metal);
            app.DropDown.Items = {'极化率因子', '球体', '圆柱体'};
            app.DropDown.Value = '圆柱体';
            app.xyzmEditFieldLabel = uilabel(app.Panel_Metal);
            app.xyzmEditFieldLabel.Text = '位置/x,y,z(m)';
            app.xyzmEditField = uieditfield(app.Panel_Metal, 'text');
            app.xyzmEditField.Value = '0 0 -1';
            app.Label_7 = uilabel(app.Panel_Metal);
            app.Label_7.Text = '姿态角';
            app.EditField = uieditfield(app.Panel_Metal, 'text');
            app.EditField.Value = '0 0 0';
            app.EditField_6Label = uilabel(app.Panel_Metal);
            app.EditField_6Label.Text = '相对磁导率';
            app.EditField_6 = uieditfield(app.Panel_Metal, 'numeric');
            app.EditField_6.Value = 1;
            app.mLabel_4 = uilabel(app.Panel_Metal);
            app.mLabel_4.Text = '半径/m';
            app.mEditField_3 = uieditfield(app.Panel_Metal, 'numeric');
            app.mEditField_3.Value = 0.2;
            app.mEditField_4Label = uilabel(app.Panel_Metal);
            app.mEditField_4Label.Text = '长度/m';
            app.mEditField_4 = uieditfield(app.Panel_Metal, 'numeric');
            app.mEditField_4.Value = 0.8;
            app.Panel_Algorithm = uipanel(app.Panel_Scene);
            app.Panel_Algorithm.Title = '算法';
            app.TabGroup = uitabgroup(app.Panel_Algorithm);
            app.Tab_Image = uitab(app.TabGroup);
            app.Tab_Image.Title = '成像定位';
            app.Tab_Inversion = uitab(app.TabGroup);
            app.Tab_Inversion.Title = '反演求参';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '训练分类';
            app.Panel_Data = uipanel(app.Panel_Scene);
            app.Panel_Data.Title = '数据';
            app.mEditField_5Label = uilabel(app.Panel_Data);
            app.mEditField_5Label.Text = '线间隔/m';
            app.mEditField_5 = uieditfield(app.Panel_Data, 'numeric');
            app.mEditField_5.Value = 0.1;
            app.mLabel_5 = uilabel(app.Panel_Data);
            app.mLabel_5.Text = '点间隔/m';
            app.mEditField_6 = uieditfield(app.Panel_Data, 'numeric');
            app.mEditField_6.Value = 0.1;
            app.mLabel_6 = uilabel(app.Panel_Data);
            app.mLabel_6.Text = '扫描高度/m';
            app.mEditField_7 = uieditfield(app.Panel_Data, 'numeric');
            app.Label_8 = uilabel(app.Panel_Data);
            app.Label_8.Text = '磁场特征';
            app.DropDown_Bxyz = uidropdown(app.Panel_Data);
            app.DropDown_Bxyz.Items = {'总场', 'Bx', 'By', 'Bz'};
            app.DropDown_Bxyz.Value = '总场';
            app.Label_9 = uilabel(app.Panel_Data);
            app.Label_9.Text = '特征选择';
            app.DropDown_Feature = uidropdown(app.Panel_Data);
            app.DropDown_Feature.Items = {'幅度', '相位', '通道', '时域响应积分'};
            app.DropDown_Feature.Value = '幅度';
            app.Button_3 = uibutton(app.Panel_Data, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '生成特征图';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MetalDetection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetalDetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetalDetectionUIFigure)
        end
    end
end