classdef MetalDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetalDetectionUIFigure  matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        Menu_2                  matlab.ui.container.Menu
        Panel_Result            matlab.ui.container.Panel
        UIAxes_Scene            matlab.ui.control.UIAxes
        UIAxes_Image            matlab.ui.control.UIAxes
        Panel_Scene             matlab.ui.container.Panel
        Panel_Model             matlab.ui.container.Panel
        Panel_Detector          matlab.ui.container.Panel
        AEditFieldLabel         matlab.ui.control.Label
        AEditField              matlab.ui.control.NumericEditField
        mEditFieldLabel         matlab.ui.control.Label
        mEditField              matlab.ui.control.NumericEditField
        ButtonGroup             matlab.ui.container.ButtonGroup
        FD                      matlab.ui.control.RadioButton
        TD                      matlab.ui.control.RadioButton
        HzEditFieldLabel        matlab.ui.control.Label
        HzEditField             matlab.ui.control.NumericEditField
        Panel_Space             matlab.ui.container.Panel
        xLabel                  matlab.ui.control.Label
        xEditField              matlab.ui.control.NumericEditField
        xEditField_2            matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        mLabel                  matlab.ui.control.Label
        yEditFieldLabel         matlab.ui.control.Label
        yEditField              matlab.ui.control.NumericEditField
        xEditField_4            matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        mLabel_2                matlab.ui.control.Label
        zEditFieldLabel         matlab.ui.control.Label
        zEditField              matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        xEditField_5            matlab.ui.control.NumericEditField
        mLabel_3                matlab.ui.control.Label
        Panel_Metal             matlab.ui.container.Panel
        smEditFieldLabel        matlab.ui.control.Label
        smEditField             matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        xyzmEditFieldLabel      matlab.ui.control.Label
        xyzmEditField           matlab.ui.control.EditField
        Label_7                 matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        EditField_6Label        matlab.ui.control.Label
        EditField_6             matlab.ui.control.NumericEditField
        mLabel_4                matlab.ui.control.Label
        mEditField_3            matlab.ui.control.NumericEditField
        mEditField_4Label       matlab.ui.control.Label
        mEditField_4            matlab.ui.control.NumericEditField
        Panel_Algorithm         matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        Tab_Image               matlab.ui.container.Tab
        Label_10                matlab.ui.control.Label
        Label_12                matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Label_11                matlab.ui.control.Label
        DropDown_2              matlab.ui.control.DropDown
        Label_13                matlab.ui.control.Label
        EditField_8             matlab.ui.control.EditField
        Panel_2                 matlab.ui.container.Panel
        Label_14                matlab.ui.control.Label
        DropDown_3              matlab.ui.control.DropDown
        Label_15                matlab.ui.control.Label
        EditField_9             matlab.ui.control.EditField
        Label_16                matlab.ui.control.Label
        Panel_3                 matlab.ui.container.Panel
        Label_17                matlab.ui.control.Label
        DropDown_4              matlab.ui.control.DropDown
        Label_18                matlab.ui.control.Label
        EditField_10            matlab.ui.control.EditField
        Tab_Inversion           matlab.ui.container.Tab
        Panel_5                 matlab.ui.container.Panel
        Label_20                matlab.ui.control.Label
        DropDown_5              matlab.ui.control.DropDown
        Label_21                matlab.ui.control.Label
        Panel_6                 matlab.ui.container.Panel
        Label_22                matlab.ui.control.Label
        DropDown_6              matlab.ui.control.DropDown
        Label_23                matlab.ui.control.Label
        Tab_3                   matlab.ui.container.Tab
        Panel_Data              matlab.ui.container.Panel
        mEditField_5Label       matlab.ui.control.Label
        mEditField_5            matlab.ui.control.NumericEditField
        mLabel_5                matlab.ui.control.Label
        mEditField_6            matlab.ui.control.NumericEditField
        mLabel_6                matlab.ui.control.Label
        mEditField_7            matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        DropDown_Bxyz           matlab.ui.control.DropDown
        Label_9                 matlab.ui.control.Label
        DropDown_Feature        matlab.ui.control.DropDown
        Button_3                matlab.ui.control.Button
        dBEditFieldLabel        matlab.ui.control.Label
        dBEditField             matlab.ui.control.NumericEditField
        Panel_4                 matlab.ui.container.Panel
        CheckBox                matlab.ui.control.CheckBox
        CheckBox_2              matlab.ui.control.CheckBox
        CheckBox_3              matlab.ui.control.CheckBox
        CheckBox_4              matlab.ui.control.CheckBox
        CheckBox_5              matlab.ui.control.CheckBox
        CheckBox_6              matlab.ui.control.CheckBox
        CheckBox_7              matlab.ui.control.CheckBox
        Output                  matlab.ui.control.TextArea
        Label_19                matlab.ui.control.Label
        Button_4                matlab.ui.control.Button
    end


    methods (Access = private)
    
        function results = Initialize(app)
            addpath(genpath('../0电磁模型/matlab'));
            addpath(genpath('../2探测定位'));
            addpath(genpath('../3模型反演'));
            addpath(genpath('../5性能评估'));
            %% Global parameter
           
            global Scene;   % 场景
            Scene=[];
            model=struct(); % 模型参数
            Space=struct();      % 空间参数
            Space.xRange=[app.xEditField.Value app.xEditField_2.Value];
            Space.yRange=[app.yEditField.Value app.xEditField_4.Value];
            Space.zRange=[app.zEditField.Value app.xEditField_5.Value];
            model.space=Space;
            detector=struct();  % 探测器
            detector.I=app.AEditField.Value;
            detector.R=app.mEditField.Value;
            detector.F=app.HzEditField.Value;
            if app.FD.Value
                detector.type="FD";
            else
                detector.type="TD";
            end
            model.detector=detector;
            metal=struct();     %金属目标
            metal.postion=double(string(split(app.xyzmEditField.Value)));
            posture=double(string(split(app.EditField.Value)));
            if string(app.DropDown.Value)=="极化率因子"
                    metal.mx=posture(4);
                    metal.my=posture(5);
                    metal.mz=posture(6);
                    metal.theta=posture(1);
                    metal.phi=posture(2);
                    metal.psi=posture(3);
            elseif(string(app.DropDown.Value)=="球体")
                    metal.theta=posture(1);
                    metal.phi=posture(2);
                    metal.psi=posture(3);
            elseif(string(app.DropDown.Value)=="圆柱体")
                  metal.theta=posture(1);
                  metal.phi=posture(2);
                  metal.psi=posture(3);
            else 
                error('金属目标类型未定义');
            end
            metal.type=string(app.DropDown.Value); 
            metal.R=app.mEditField_3.Value;
            metal.L=app.mEditField_4.Value;          
            metal.E=app.smEditField.Value;
            metal.U=app.EditField_6.Value;
            model.metal=metal;
            Scene.model=model;
            
            
            dataconf=struct();%%  数据参数
            dataconf.linespace=app.mEditField_5.Value;
            dataconf.interval=app.mEditField_6.Value;
            dataconf.height=app.mEditField_7.Value;
            dataconf.Bxyz=app.DropDown_Bxyz.Value;
            dataconf.feature=app.DropDown_Feature.Value;
            dataconf.SNR=app.dBEditField.Value;
            dataconf.v_x=Space.xRange(1):dataconf.linespace:Space.xRange(2);
            dataconf.v_y=Space.yRange(1):dataconf.interval:Space.yRange(2);
            [dataconf.m_x,dataconf.m_y]=meshgrid(dataconf.v_x,dataconf.v_y);        % 观测面扫描网格
            Scene.dataconf=dataconf;
            
            algorithm=struct();%% 算法参数
            algorithm.method=app.TabGroup.SelectedTab.Title;
            algorithm.preprocess.method=app.DropDown_2.Value;
            algorithm.preprocess.parameter=app.EditField_8.Value;
            
            algorithm.segmentation.method=app.DropDown_3.Value;
            algorithm.segmentation.parameter=app.EditField_9.Value;
            
            algorithm.location.method=app.DropDown_4.Value;
            algorithm.location.parameter=app.EditField_10.Value;
            
            algorithm.inversion.model=app.DropDown_5.Value;         % 反演模型
            algorithm.inversion.method=app.DropDown_6.Value;        % 反演方法
            Scene.algorithm=algorithm;
            CaluateField(app);
            %% 输出参数配置
            outconf=struct();
            outconf.autosave=app.CheckBox.Value;
            outconf.autosavepicture=app.CheckBox_2.Value;
            outconf.plot=app.CheckBox_7.Value;
            outconf.featureimage=app.CheckBox_3.Value;
            outconf.preprocess=app.CheckBox_4.Value;
            outconf.segmentation=app.CheckBox_5.Value;
            outconf.location=app.CheckBox_6.Value;
            Scene.outconf=outconf;
            
            %% 输出参数初始化
            Scene.result.count=0;
            results=1;
            
        end
        function results = AutoUpdateParemeter(app)
            %% Global parameter
           
            global Scene;   % 场景
            model=struct(); % 模型参数
            Space=struct();      % 空间参数
            Space.xRange=[app.xEditField.Value app.xEditField_2.Value];
            Space.yRange=[app.yEditField.Value app.xEditField_4.Value];
            Space.zRange=[app.zEditField.Value app.xEditField_5.Value];
            model.space=Space;
            detector=struct();  % 探测器
            detector.I=app.AEditField.Value;
            detector.R=app.mEditField.Value;
            detector.F=app.HzEditField.Value;
            if app.FD.Value
                detector.type="FD";
            else
                detector.type="TD";
            end
            model.detector=detector;
            metal=struct();     %金属目标
            metal.postion=double(string(split(app.xyzmEditField.Value)));
            posture=double(string(split(app.EditField.Value)));
            if string(app.DropDown.Value)=="极化率因子"
                    metal.mx=posture(4);
                    metal.my=posture(5);
                    metal.mz=posture(6);
                    metal.theta=posture(1);
                    metal.phi=posture(2);
                    metal.psi=posture(3);
            elseif(string(app.DropDown.Value)=="球体")
                    metal.theta=posture(1);
                    metal.phi=posture(2);
                    metal.psi=posture(3);
            elseif(string(app.DropDown.Value)=="圆柱体")
                  metal.theta=posture(1);
                  metal.phi=posture(2);
                  metal.psi=posture(3);
            else 
                error('金属目标类型未定义');
            end
            metal.type=string(app.DropDown.Value); 
            metal.R=app.mEditField_3.Value;
            metal.L=app.mEditField_4.Value;          
            metal.E=app.smEditField.Value;
            metal.U=app.EditField_6.Value;
            model.metal=metal;
            Scene.model=model;
            
            
            dataconf=struct();%%  数据参数
            dataconf.linespace=app.mEditField_5.Value;
            dataconf.interval=app.mEditField_6.Value;
            dataconf.height=app.mEditField_7.Value;
            dataconf.Bxyz=app.DropDown_Bxyz.Value;
            dataconf.feature=app.DropDown_Feature.Value;
            dataconf.SNR=app.dBEditField.Value;
            dataconf.v_x=Space.xRange(1):dataconf.linespace:Space.xRange(2);
            dataconf.v_y=Space.yRange(1):dataconf.interval:Space.yRange(2);
            [dataconf.m_x,dataconf.m_y]=meshgrid(dataconf.v_x,dataconf.v_y);        % 观测面扫描网格
            Scene.dataconf=dataconf;
            
            %% 输出参数初始化
            Scene.result.count=0;
            results=1;
        end
        function results = ShowScene(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            % Scene
            hold(app.UIAxes_Scene,'off'); 
            plot3(app.UIAxes_Scene,Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.dataconf.height*ones(size(Scene.dataconf.m_y)),'-k');
            set(app.UIAxes_Scene,'ZLim',Scene.model.space.zRange);
            hold(app.UIAxes_Scene,'on'); 
            plot3(app.UIAxes_Scene,Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.dataconf.height*ones(size(Scene.dataconf.m_y)),'or');
                % geneate metal object cylinder or sphere
            if Scene.model.metal.type=="球体"
                r=Scene.model.metal.R;
                x=Scene.model.metal.postion(1);
                y=Scene.model.metal.postion(2);
                z=Scene.model.metal.postion(3);
                [X,Y,Z]=sphere(100);
                X=r*X+x;
                Y=r*Y+y;
                Z=r*Z+z;
                copper=[231 194 97]/255;
                surf(app.UIAxes_Scene,X,Y,Z,'FaceColor',copper,'EdgeColor','none');
                camlight(app.UIAxes_Scene); 
                lighting(app.UIAxes_Scene,'gouraud');
                axis(app.UIAxes_Image,'equal');
            elseif Scene.model.metal.type=="圆柱体"
                r=Scene.model.metal.R;
                h=Scene.model.metal.L;
                x=Scene.model.metal.postion(1);
                y=Scene.model.metal.postion(2);
                z=Scene.model.metal.postion(3);
                N=20;
                [X,Y,Z]=cylinder(r,N);
                Z=Z-0.5;
                Z=h*Z/2;
                Rt=RotationTensor(Scene.model.metal.theta*pi/180,Scene.model.metal.phi*pi/180,Scene.model.metal.psi*pi/180);
                XX=X(2,: );
                YY=Y(2,: );
                Z1=Z(1,: );
                Z2=Z(2,: );
                for i=1:N+1
                    r0=[X(1,i),Y(1,i),Z(1,i)]';
                    r1=Rt*r0;
                    XC(1,i)=r1(1);YC(1,i)=r1(2);ZC(1,i)=r1(3);
                    r0=[X(2,i),Y(2,i),Z(2,i)]';
                    r1=Rt*r0;
                    XC(2,i)=r1(1);YC(2,i)=r1(2);ZC(2,i)=r1(3);
                    
                    r0=[XX(i),YY(i),Z1(i)]';
                    rT=Rt*r0;
                    XX1C(i)=rT(1);YY1C(i)=rT(2);Z1C(i)=rT(3);
                    r=[XX(i),YY(i),Z2(i)]';
                    rT=Rt*r;
                    XX2C(i)=rT(1);YY2C(i)=rT(2);Z2C(i)=rT(3);
                     
                    
                end
                copper=[231 194 97]/255;
                fill3(app.UIAxes_Scene,XX1C+x,YY1C+y,Z1C+z,copper,'EdgeColor','none');
               
                fill3(app.UIAxes_Scene,XX2C+x,YY2C+y,Z2C+z,copper,'EdgeColor','none');

                XC=XC+x;YC=YC+y;ZC=ZC+z;
                surf(app.UIAxes_Scene,XC,YC,ZC,'FaceColor',copper,'EdgeColor','none');
                camlight(app.UIAxes_Scene); 
                lighting(app.UIAxes_Scene,'gouraud');
                axis(app.UIAxes_Image,'equal');
            elseif Scene.model.metal.type=="极化率因子"
                polar=[Scene.model.metal.mx Scene.model.metal.my Scene.model.metal.mz];
                A=1/sqrt(sum(polar.^2));
                polar=polar*A;
                x=[Scene.model.metal.postion(1) Scene.model.metal.postion(1)+A*polar(1)];
                y=[Scene.model.metal.postion(2) Scene.model.metal.postion(2)+A*polar(2)];
                z=[Scene.model.metal.postion(3) Scene.model.metal.postion(3)+A*polar(3)];
                q=quiver3(app.UIAxes_Scene,x(1),y(1),z(1),polar(1),polar(2),polar(3),'k','LineWidth',1);
                str=[' 极化率(' num2str(Scene.model.metal.mx) ',' num2str(Scene.model.metal.my) ',' num2str(Scene.model.metal.mz) ')'];
                text(app.UIAxes_Scene,x(1),y(1),z(1),str);
                q.MaxHeadSize=5;
            end
            xlabel(app.UIAxes_Scene,'x/m');
            ylabel(app.UIAxes_Scene,'y/m');
            zlabel(app.UIAxes_Scene,'z/m');
            title(app.UIAxes_Scene,'探测场景');
            % Feature map
            colorbar(app.UIAxes_Image,'off');
            hold(app.UIAxes_Image,'off'); 
            contourf(app.UIAxes_Image,Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.result.Bxyz,6);
            xlabel(app.UIAxes_Image,'x/m');
            ylabel(app.UIAxes_Image,'y/m');
            title(app.UIAxes_Image,'扫描图');
            colormap(app.UIAxes_Image,parula);
            colorbar(app.UIAxes_Image);
            axis(app.UIAxes_Image,'equal');
            
            % other image
%             figure('name','3-D figure of orignal data')
%             surf(Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.result.Bxyz);
%             title('orignal data');
%             xlabel("x/m");
%             ylabel("y/m");
%             zlabel("z/m");

            
        end
        
        function results = CaluateField(app)
              global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            k=0;
            Hx=zeros(size(Scene.dataconf.m_x));
            Hy=zeros(size(Scene.dataconf.m_x));
            Hz=zeros(size(Scene.dataconf.m_x));
            if Scene.model.metal.type=="极化率因子"
                for i=1:length(Scene.dataconf.v_y)          % 对二维区域的单元格进行扫描，相对于移动线圈
                    for j=1:length(Scene.dataconf.v_x)
                        k=k+1;
                        x=Scene.dataconf.v_x(j);            % 当前线圈的x坐标
                        y=Scene.dataconf.v_y(i);            % 当前线圈的y坐标
                        Coil=struct();
                        Coil.Postion=[x y Scene.dataconf.height];  % 线圈位置，默认高度为0保持不变   
                        Coil.I=Scene.model.detector.I;
                        Coil.R=Scene.model.detector.R;
                        Coil.f=Scene.model.detector.F;
                        Target=struct();
                        Target.Postion=Scene.model.metal.postion';
                        Target.MagPolar=[ Scene.model.metal.mx  Scene.model.metal.my  Scene.model.metal.mz];
                        Target.Theta=Scene.model.metal.theta*pi/180;
                        Target.Phi=Scene.model.metal.phi*pi/180;
                        Target.Psi=Scene.model.metal.psi*pi/180;
                        [Hx(i,j),Hy(i,j),Hz(i,j)]=SecondField(Coil,Target,struct('Postion', Coil.Postion));           % 计算当前位置二次场  
                    end
                end
            elseif Scene.model.metal.type=="球体"
                for i=1:length(Scene.dataconf.v_y)          % 对二维区域的单元格进行扫描，相对于移动线圈
                    for j=1:length(Scene.dataconf.v_x)
                        x=Scene.dataconf.v_x(j);            % 当前线圈的x坐标
                        y=Scene.dataconf.v_y(i);            % 当前线圈的y坐标
                        CoilPostion=[x y Scene.dataconf.height]; % 线圈位置，默认高度为0   
                        h=Scene.dataconf.height;
                        I=Scene.model.detector.I;
                        R=Scene.model.detector.R;
                        f=Scene.model.detector.F;
                        SphereR=Scene.model.metal.R;
                        u=Scene.model.metal.U*10^7;
                        sigma=Scene.model.metal.E;
                        postion=Scene.model.metal.postion';
                        SphereMxyz=MomSphere(FirstField(I,R,f,(postion-CoilPostion)),u,sigma,f,SphereR);  % 计算磁矩，SpherePostion-CoilPostion即物体相对于线圈的位置
                        SpereMM=[postion(:) SphereMxyz(:)];                                               % 定义磁矩参数
                        [Hx(i,j),Hy(i,j),Hz(i,j)]=HFieldModel(SpereMM,x,y,h);             % 计算当前位置二次场
                        
                    end
                end
            elseif Scene.model.metal.type=="圆柱体"
                for i=1:length(Scene.dataconf.v_y)          % 对二维区域的单元格进行扫描，相对于移动线圈
                    for j=1:length(Scene.dataconf.v_x)
                        x=Scene.dataconf.v_x(j);            % 当前线圈的x坐标
                        y=Scene.dataconf.v_y(i);            % 当前线圈的y坐标
                        CoilPostion=[x y Scene.dataconf.height]; % 线圈位置，默认高度为0   
                        h=Scene.dataconf.height;
                        I=Scene.model.detector.I;
                        R=Scene.model.detector.R;
                        f=Scene.model.detector.F;
                        CR=Scene.model.metal.R;
                        p=Scene.model.metal.L/(2*CR);
                        Cu=Scene.model.metal.U*10^7;
                        Csigma=Scene.model.metal.E;
                        postion=Scene.model.metal.postion';


                        theta=Scene.model.metal.theta*pi/180;
                        phi=Scene.model.metal.phi*pi/180;
                        if j==22&&i==21
                            phi;
                        end
                        CMxyz=MomCylinder(FirstField(I,R,f,(postion-CoilPostion)),Cu,Csigma,f,p,theta,phi,CR);
                        CMM=[postion(:) CMxyz(:)];
                        [Hx(i,j),Hy(i,j),Hz(i,j)]=HFieldModel(CMM,x,y,h);             % 计算当前位置二次场
                   
                    end
                end
            end
            if Scene.dataconf.feature=="幅度"
               nHx=awgn(abs(Hx),Scene.dataconf.SNR,'measured');
               nHy=awgn(abs(Hy),Scene.dataconf.SNR,'measured');
               nHz=awgn(abs(Hz),Scene.dataconf.SNR,'measured');
            elseif Scene.dataconf.feature=="相位"
               nHx=awgn(abs(Hx),Scene.dataconf.SNR,'measured');
               nHy=awgn(abs(Hy),Scene.dataconf.SNR,'measured');
               nHz=awgn(abs(Hz),Scene.dataconf.SNR,'measured');         
            end
             Scene.result.Hx=Hx;
             Scene.result.Hy=Hy;
             Scene.result.Hz=Hz;
             Scene.result.nHx=nHx;
             Scene.result.nHy=nHy;
             Scene.result.nHz=nHz;
            if Scene.dataconf.Bxyz=="总场"
                Scene.result.Bxyz=sqrt(nHx.^2+nHy.^2+nHz.^2);
                Scene.result.Bxyz0=abs(sqrt(Hx.^2+Hy.^2+Hz.^2));
            elseif Scene.dataconf.Bxyz=="Bx"
                Scene.result.Bxyz=nHx;
                Scene.result.Bxyz0=abs(Hx);
            elseif Scene.dataconf.Bxyz=="By"
                Scene.result.Bxyz=nHy;
                Scene.result.Bxyz0=abs(Hy);
            elseif Scene.dataconf.Bxyz=="Bz"
                Scene.result.Bxyz=nHz;
                Scene.result.Bxyz0=abs(Hz);
            end
%             save(sprintf('theta=%d,phi=%d.mat',Scene.model.metal.theta,Scene.model.metal.phi),'Scene');
        end
        
        function results = UpdateParameter(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            algorithm=struct();%% 算法参数
            algorithm.method=app.TabGroup.SelectedTab.Title;
            algorithm.preprocess.method=app.DropDown_2.Value;
            algorithm.preprocess.parameter=app.EditField_8.Value;
            
            algorithm.segmentation.method=app.DropDown_3.Value;
            algorithm.segmentation.parameter=app.EditField_9.Value;
            
            algorithm.location.method=app.DropDown_4.Value;
            algorithm.location.parameter=app.EditField_10.Value;
            algorithm.inversion.model=app.DropDown_5.Value;         % 反演模型
            algorithm.inversion.method=app.DropDown_6.Value;        % 反演方法
            Scene.algorithm=algorithm;
            Scene.dataconf.SNR=app.dBEditField.Value;
             %% 输出参数配置
            outconf=struct();
            outconf.autosave=app.CheckBox.Value;
            outconf.autosavepicture=app.CheckBox_2.Value;
            outconf.plot=app.CheckBox_7.Value;
            outconf.featureimage=app.CheckBox_3.Value;
            outconf.preprocess=app.CheckBox_4.Value;
            outconf.segmentation=app.CheckBox_5.Value;
            outconf.location=app.CheckBox_6.Value;
            Scene.outconf=outconf;
            
            
        end
        function results = CorruptSignal(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            if Scene.dataconf.feature=="幅度"
               nHx=awgn(abs(Scene.result.Hx),Scene.dataconf.SNR,'measured');
               nHy=awgn(abs(Scene.result.Hy),Scene.dataconf.SNR,'measured');
               nHz=awgn(abs(Scene.result.Hz),Scene.dataconf.SNR,'measured');
            elseif Scene.dataconf.feature=="相位"
               nHx=awgn(abs(Hx),Scene.dataconf.SNR,'measured');
               nHy=awgn(abs(Hy),Scene.dataconf.SNR,'measured');
               nHz=awgn(abs(Hz),Scene.dataconf.SNR,'measured');         
            end
             Scene.result.nHx=nHx;
             Scene.result.nHy=nHy;
             Scene.result.nHz=nHz;
            if Scene.dataconf.Bxyz=="总场"
                Scene.result.Bxyz=abs(sqrt(nHx.^2+nHy.^2+nHz.^2));
            elseif Scene.dataconf.Bxyz=="Bx"
                Scene.result.Bxyz=nHx;
            elseif Scene.dataconf.Bxyz=="By"
                Scene.result.Bxyz=nHy;
            elseif Scene.dataconf.Bxyz=="Bz"
                Scene.result.Bxyz=nHz;
            end
        end        
        function results = Preprocess(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            CorruptSignal(app);
            ShowScene(app);
            preprocess=Scene.algorithm.preprocess;
            Scene.result.output=['降噪方法：',preprocess.method,'\r\n'];
            if string(preprocess.method)=="高斯"
                Scene.result.denBxyz=filter2(fspecial('gaussian',3),Scene.result.Bxyz);
               
            elseif string(preprocess.method)=="均值"
                Scene.result.denBxyz=filter2(fspecial('average',3),Scene.result.Bxyz);
            elseif string(preprocess.method)=="小波"
                [thr,sorh,keepapp]=ddencmp('den','wv',Scene.result.Bxyz);  
                Scene.result.denBxyz=wdencmp('gbl',Scene.result.Bxyz,'sym4',2,thr,sorh,keepapp); 
            else 
                Scene.result.denBxyz=Scene.result.Bxyz;
            end
        end
        
        
        function results = Segmentation(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            segmentation=Scene.algorithm.segmentation;
            Scene.result.output=[Scene.result.output '分割方法：',segmentation.method,newline];
            if string(segmentation.method)=="阈值"
                Gnoise=Scene.result.Bxyz-Scene.result.Bxyz0;
                Threshold=str2double(segmentation.parameter)*std(Gnoise(:));
                AnomImage=PickAnomaly(Scene.result.denBxyz,Threshold);
                Scene.result.region=AnomImage;
                Scene.result.edge=logical(edge(AnomImage,1));
                
            elseif string(segmentation.method)=="CV-Active"
                nrow=length(Scene.dataconf.v_y);
                ncol=length(Scene.dataconf.v_x);
                ic=nrow/2;
                jc=ncol/2;
                parameter=double(string(split(segmentation.parameter)));
                r=parameter(1);                 %起始圆半径
                initialLSF = sdf2circle(nrow,ncol,ic,jc,r);
                u=initialLSF;
                if length(parameter)>1
                    numIter = parameter(2);  
                else
                    numIter = 500;  
                end
                timestep = 0.5;
                lambda_1=1;
                lambda_2=1;
                h = 1;
                epsilon=1;
                nu = 0.001*255*255;  % 对于不同的图像，调整此参数
                EdgeCV=EVOL_CV(mapminmax(Scene.result.denBxyz,0,255), u, nu, lambda_1, lambda_2, timestep, epsilon, numIter);
                img=1*(EdgeCV>0);
                ed=edge(img,1);
                Scene.result.region=(~img);
                Scene.result.edge=(ed>1);
            elseif string(segmentation.method)=="Sobel算子"
                Scene.result.edge=(edge((uint8(mapminmax(Scene.result.denBxyz,0,255))),1)>20);
                Scene.result.region=Scene.result.edge;
            end
        end
        
        function results = Location(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            location=Scene.algorithm.location;
            Scene.result.output=['定位方法：',location.method newline];
            if string(location.method)=="峰值"
                [Hmax,index]=PeakDetection(Scene.result.denBxyz.*Scene.result.region);
                Scene.result.location=[Scene.dataconf.m_x(index(1),index(2)),Scene.dataconf.m_y(index(1),index(2)),0];
            elseif string(location.method)=="几何不变矩"
                 x=sum(sum(Scene.result.denBxyz.*Scene.dataconf.m_x.*Scene.result.region))/sum(sum(Scene.result.denBxyz));
                 y=sum(sum(Scene.result.denBxyz.*Scene.dataconf.m_y.*Scene.result.region))/sum(sum(Scene.result.denBxyz));
                 Scene.result.location=[x,y,0];
            elseif string(location.method)=="霍夫变换"
                parameter=double(string(split(location.parameter)));
                step_r = 1;
                step_angle = 0.1;
                
                if length(parameter)==1
                    minr=parameter(1);
                    maxr = min(length(Scene.dataconf.v_x),length(Scene.dataconf.v_y))/2;
                elseif length(parameter)>=2
                    minr=parameter(1);
                    maxr=parameter(2);  
                else 
                   minr = 5;
                   maxr = min(length(Scene.dataconf.v_x),length(Scene.dataconf.v_y))/2;
                end
                if isnan(minr)
                     minr = 5;
                end
                if isnan(maxr)
                   maxr = min(length(Scene.dataconf.v_x),length(Scene.dataconf.v_y))/2;
                    
                end
                thresh = 0.8;
                BW=Scene.result.edge;
                [hough_space,hough_circle,para] = FindCircle(BW,step_r,step_angle,minr,maxr,thresh); 
                Scene.result.location=[Scene.model.space.xRange(1)+(para(1,2)-1)*Scene.dataconf.linespace,...
                                       Scene.model.space.yRange(1)+(para(1,1)-1)*Scene.dataconf.interval,0];
                Scene.result.hough_circleParaXYR=para;
            end
            Scene.result.output=[Scene.result.output '定位结果' newline 'x=' num2str(Scene.result.location(1)) 'm' newline...
                                'y=' num2str(Scene.result.location(2)) 'm' newline];
            Scene.result.output=[Scene.result.output '实际位置' newline 'x=' num2str(Scene.model.metal.postion(1)) 'm' newline...
                                'y=' num2str(Scene.model.metal.postion(2)) 'm' newline 'z=' num2str(Scene.model.metal.postion(3)) 'm' newline];
            Scene.result.output=[Scene.result.output '定位误差' newline 'x=' num2str(abs(Scene.result.location(1)-Scene.model.metal.postion(1))) 'm' newline...
                                'y=' num2str(abs(Scene.result.location(2)-Scene.model.metal.postion(2))) 'm' newline];              
        end
        function results = Inversion(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            inversion=Scene.algorithm.inversion;
             Coil=struct();
             Coil.Postion=[0 0 Scene.dataconf.height];  % 线圈位置，默认高度为0保持不变   
             Coil.I=Scene.model.detector.I;
             Coil.R=Scene.model.detector.R;
             Coil.f=Scene.model.detector.F;
            Coils(length(Scene.dataconf.v_y)*length(Scene.dataconf.v_x))=struct(Coil);
             Scene.result.output=['反演模型：',inversion.model newline];
             Scene.result.output=['反演方法：',inversion.method newline];
            if string(inversion.model)=="单偶极子"
                
                 k=0;
                 for i=1:length(Scene.dataconf.v_x)          % 对二维区域的单元格进行扫描，相对于移动线圈
                    for j=1:length(Scene.dataconf.v_y)
                        k=k+1;
                        x=Scene.dataconf.v_x(i);            % 当前线圈的x坐标
                        y=Scene.dataconf.v_y(j);            % 当前线圈的y坐标
                        Coil=struct();
                        Coil.Postion=[x y Scene.dataconf.height];  % 线圈位置，默认高度为0保持不变   
                        Coil.I=Scene.model.detector.I;
                        Coil.R=Scene.model.detector.R;
                        Coil.f=Scene.model.detector.F;
                        Coils(k)=Coil;
                    end
                end
            elseif string(inversion.model)=="ONVMS"
                
            elseif string(inversion.model)=="NSMS"
                
            end 
            
            if string(inversion.method)=="牛顿法"
                AlphaTrue=[Scene.model.metal.postion(:)' Scene.model.metal.theta Scene.model.metal.phi Scene.model.metal.psi 1 1 1]; % 真值
                 x=sum(sum(Scene.result.denBxyz.*Scene.dataconf.m_x))/sum(sum(Scene.result.denBxyz));
                 y=sum(sum(Scene.result.denBxyz.*Scene.dataconf.m_y))/sum(sum(Scene.result.denBxyz));

                Alpha0=[x y -1 0 0 0 1 1 1];   % 迭代初值，依次为x,y,z,θ，φ，ψ，βx,βy,βz
                H=Scene.result.nHz(:);
                fun = @(x)HzAlpha(x,Coils)-H;
                options = optimoptions(@lsqnonlin,'Algorithm','levenberg-marquardt');
                [x,resnorm,residual,exitflag,output,lambda,jacobian]  = lsqnonlin(fun,Alpha0,[],[],options);
                resnorm
                output
                x
                AlphaTrue
                errors=abs(AlphaTrue-x)
                Scene.result.location=x(1:3);
                Scene.result.output=[Scene.result.output '【结果】' newline '俯仰角=' num2str(x(4)*180/pi) '' newline...
                                            '航向角=' num2str(x(5)*180/pi) '' newline...
                                            '滚转角=' num2str(x(6)*180/pi) '' newline];
                Scene.result.output=[Scene.result.output '极化率=[' num2str(x(7)) ',' num2str(x(8)) ',' num2str(x(9)) ']A/m' newline];

                 Scene.result.output=[Scene.result.output '定位结果' newline 'x=' num2str(Scene.result.location(1)) 'm' newline...
                                'y=' num2str(Scene.result.location(2)) 'm' newline 'z=' num2str(Scene.result.location(3)) 'm' newline];
            Scene.result.output=[Scene.result.output '实际位置' newline 'x=' num2str(Scene.model.metal.postion(1)) 'm' newline...
                                'y=' num2str(Scene.model.metal.postion(2)) 'm' newline 'z=' num2str(Scene.model.metal.postion(3)) 'm' newline];
            Scene.result.output=[Scene.result.output '定位误差' newline 'x=' num2str(abs(Scene.result.location(1)-Scene.model.metal.postion(1))) 'm' newline...
                                'y=' num2str(abs(Scene.result.location(2)-Scene.model.metal.postion(2))) 'm' newline...
                                'z=' num2str(abs(Scene.result.location(3)-Scene.model.metal.postion(3))) 'm' newline];     
            elseif string(inversion.method)=="模拟退火"
                
            elseif string(inversion.method)=="遗传变异"
                
            elseif string(inversion.method)=="其他"
                
            end
                     
        end
        function results = ShowResults(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            outconf=Scene.outconf;
            if outconf.autosave   % 自动保存结果分析报告至文件
                Save(app);
            end
            if string(Scene.algorithm.method)=="反演求参"
                return ;
            end
            if outconf.plot       % 显示GUI界面
                if outconf.featureimage     % 显示特征图
                    PlotFeatrueImage(app);
                    SavePicture(app,Scene.result.fig_feature,"orignal data");
                end
                if outconf.preprocess       % 显示降噪结果
                    PlotPreprocess(app);
                    SavePicture(app,Scene.result.fig_preprocess,"denoise result");
                end
                if outconf.segmentation     % 显示分割结果
                    PlotSegmentation(app);
                    SavePicture(app,Scene.result.fig_segementation,string(Scene.result.fig_segementation.CurrentAxes.Title.String));
                end
                if outconf.location         % 显示定位结果
                    PlotLocation(app);
                    SavePicture(app,Scene.result.fig_location,string(Scene.result.fig_location.CurrentAxes.Title.String));
                end

            end
        end
        function results = GetSavePath(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            results=sprintf("metal=%s;SNR=%ddB;feature=%s;algorithm=%s;",string(Scene.model.metal.type),...
                                                                         Scene.dataconf.SNR,...
                                                                         string(Scene.dataconf.Bxyz),...
                                                                          string(Scene.algorithm.location.method));
        end
        function results = Save(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            if exist('output')~=7  % 目录output不存在
                mkdir('output');
            end
            path=sprintf("output/%s",GetSavePath(app));
            if exist(path)~=7
                mkdir(char(path));
            end 
            name=sprintf("%s/reports_%s_%s_%s.txt",path,string(Scene.model.detector.type),string(Scene.model.metal.type),string(Scene.algorithm.location.method));
            file=fopen(name,'a+');
            if file~=-1
                content=Scene.result.output;
                 fprintf(file,"%s\r\n",content);
                 fclose(file);
            end
           
            results=0;
        end
        
        function results = SavePicture(app,fig,name)
             global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            if ~Scene.outconf.autosavepicture
                return ;
            end
            if exist('output')~=7  % 目录output不存在
                mkdir('output');
            end
            path=sprintf("output/%s",GetSavePath(app));
            if exist(path)~=7
                mkdir(char(path));
            end 
            fullname=sprintf("%s/%s",path,name);
            print(fig,fullname,"-dpng","-r600");
            results=0;
        end
        
        function results = PlotFeatrueImage(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            if isfield(Scene.result,'fig_feature')
                if isvalid(Scene.result.fig_feature)
                    figure(Scene.result.fig_feature);
                else 
                    fig=figure('name','3-D figure of orignal data');
                    Scene.result.fig_feature=fig;
                end
                
            else 
                fig=figure('name','3-D figure of orignal data');
                Scene.result.fig_feature=fig;
            end
            subplot(2,1,1);
            surf(Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.result.Bxyz0);
%             axis(gca,'square');
            title('orignal data');
            xlabel('x/m');
            ylabel('y/m');
            zlabel('z/m');
            subplot(2,1,2);
            surf(Scene.dataconf.m_x,Scene.dataconf.m_y,Scene.result.Bxyz);
            xlabel('x/m');
            ylabel('y/m');
            zlabel('z/m');
            title('orignal data corrupted by noise');
%             axis(gca,'square');
            results=0;
        end
        
        
        function results = PlotPreprocess(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end

            if isfield(Scene.result,'fig_preprocess')
                if isvalid(Scene.result.fig_preprocess)
                    figure(Scene.result.fig_preprocess);
                else 
                    fig=figure('name','Result of Preprocess');
                    Scene.result.fig_preprocess=fig;
                end
                
            else 
                fig=figure('name','Result of Preprocess');
                Scene.result.fig_preprocess=fig;
            end
            subplot(2,2,1);
            image(Scene.dataconf.v_x,Scene.dataconf.v_y,uint8(mapminmax(Scene.result.Bxyz0,0,255)));
            axis(gca,'square');
            title('orignal image');
            xlabel('x/m');
            ylabel('y/m');
            subplot(2,2,2);
            image(Scene.dataconf.v_x,Scene.dataconf.v_y,uint8(mapminmax(Scene.result.Bxyz,0,255)));
            axis(gca,'square');
            title('Feature image corrupted by noise');
            xlabel('x/m');
            ylabel('y/m');
            subplot(2,2,3);
            image(Scene.dataconf.v_x,Scene.dataconf.v_y,uint8(mapminmax(Scene.result.denBxyz,0,255)));
            axis(gca,'square');
            title('Denoising image');
            xlabel('x/m');
            ylabel('y/m');
            results=0;
        end
        function results = PlotSegmentation(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            if isfield(Scene.result,'fig_segementation')
                if isvalid(Scene.result.fig_segementation)
                    figure(Scene.result.fig_segementation);
                else 
                    fig=figure('name','Result of Segementation');
                    Scene.result.fig_segementation=fig;
                end
                
            else 
                fig=figure('name','Result of Segementation');
                Scene.result.fig_segementation=fig;
            end
            image(Scene.dataconf.v_x,Scene.dataconf.v_y,uint8(mapminmax(Scene.result.Bxyz,0,255)));
            axis(gca,'square');
            str=['Segementation result of ' Scene.algorithm.segmentation.method];
            hold on;
%             contour(Scene.dataconf.v_x,Scene.dataconf.v_y,10*Scene.result.edge,[5 5],'r');
            xlabel('x/m');
            ylabel('y/m');
            title(str);
            for i=1:size(Scene.result.edge,1)
                for j=1:size(Scene.result.edge,2)
                    if Scene.result.edge(i,j)
                        plot(Scene.model.space.xRange(1)+(j-1)*Scene.dataconf.linespace,Scene.model.space.yRange(1)+(i-1)*Scene.dataconf.interval,'r.');
                        hold on;
                    end
                end
            end
            results=0;
        end
        function results = PlotLocation(app)
            global Scene;
            if isempty(Scene)
                error('Scene need to be initialized');
            end
            if isfield(Scene.result,'fig_location')
                if isvalid(Scene.result.fig_location)
                    figure(Scene.result.fig_location);
                else 
                    fig=figure('name','Result of location');
                    Scene.result.fig_location=fig;
                end
                
            else 
                fig=figure('name','Result of location');
                Scene.result.fig_location=fig;
            end
            str=['location result of ' Scene.algorithm.location.method];
            image(Scene.dataconf.v_x,Scene.dataconf.v_y,uint8(mapminmax(Scene.result.Bxyz,0,255)));
            hold on;
            plot(Scene.model.metal.postion(1),Scene.model.metal.postion(2),'rx');
            plot(Scene.result.location(1),Scene.result.location(2),'b*');
            axis(gca,'square');
            xlabel('x/m');
            ylabel('y/m');
            title(str);
            if string(Scene.algorithm.location.method)=="霍夫变换"
                 circleParaXYR=Scene.result.hough_circleParaXYR;
                 t=0:0.01*pi:2*pi;
                 x=cos(t).*circleParaXYR(1,3)+circleParaXYR(1,2);y=sin(t).*circleParaXYR(1,3)+circleParaXYR(1,1);
                 plot((x-1)*Scene.dataconf.linespace+Scene.model.space.xRange(1),(y-1)*Scene.dataconf.interval+Scene.model.space.yRange(1),'r-');
            end
            results=0;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Initialize(app);
            ShowScene(app);

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            AutoUpdateParemeter(app);
            CaluateField(app);
            ShowScene(app);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            UpdateParameter(app);
            Preprocess(app);
            Segmentation(app);
            global Scene;
            Scene.result.output='';
            if string(Scene.algorithm.method)=="成像定位"
                 Location(app);
            elseif string(Scene.algorithm.method)=="反演求参"
                Inversion(app);
            end

            Scene.result.count=Scene.result.count+1;
            Scene.result.output=['【运行结果】' newline '总次数：' num2str(Scene.result.count) newline Scene.result.output];
            app.Output.Value=char(Scene.result.output);
            ShowResults(app);
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if value
                app.CheckBox_2.Enable='on';
                app.CheckBox_3.Enable='on';
                app.CheckBox_4.Enable='on';
                app.CheckBox_5.Enable='on';
                app.CheckBox_6.Enable='on';
            else 
                app.CheckBox_2.Enable='off';
                app.CheckBox_3.Enable='off';
                app.CheckBox_4.Enable='off';
                app.CheckBox_5.Enable='off';
                app.CheckBox_6.Enable='off';
            end
        end

        % Callback function: AEditField, ButtonGroup, DropDown, 
        % DropDown_Bxyz, DropDown_Feature, EditField, EditField_6, 
        % HzEditField, dBEditField, mEditField, mEditField_3, 
        % mEditField_4, mEditField_5, mEditField_6, mEditField_7, 
        % smEditField, xEditField, xEditField_2, xEditField_4, 
        % xEditField_5, xyzmEditField, yEditField, zEditField
        function DropDown_FeatureValueChanged(app, event)

            AutoUpdateParemeter(app);
            CaluateField(app);
            ShowScene(app);
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if string(value)=="阈值"
                app.Label_15.Text='阈值/噪声倍数';
                app.EditField_9.Value='10';
                app.EditField_9.Visible='on';
            elseif string(value)=="CV-Active"
                app.Label_15.Text='初始半径';
                app.EditField_9.Value='5';
                app.EditField_9.Visible='on';
            elseif string(value)=="Sobel算子"
                app.Label_15.Text='';
                app.EditField_9.Visible='off';
                
            end
        end

        % Callback function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
             if string(value)=="霍夫变换"
                app.Label_18.Text='半径范围/像素';
                if string(app.EditField_10.Value)==""
                    app.EditField_10.Value='5';
                end
                app.EditField_10.Visible='on';
             else
                app.EditField_10.Visible='off';
                app.Label_18.Text=' ';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetalDetectionUIFigure = uifigure;
            app.MetalDetectionUIFigure.Name = 'MetalDetection';
            app.Menu = uimenu(app.MetalDetectionUIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.MetalDetectionUIFigure);
            app.Menu_2.Text = '配置';
            app.Panel_Result = uipanel(app.MetalDetectionUIFigure);
            app.Panel_Result.Title = '结果';
            app.UIAxes_Scene = uiaxes(app.Panel_Result);
            title(app.UIAxes_Scene, 'Title')
            xlabel(app.UIAxes_Scene, 'X')
            ylabel(app.UIAxes_Scene, 'Y')
            app.UIAxes_Image = uiaxes(app.Panel_Result);
            title(app.UIAxes_Image, 'Title')
            xlabel(app.UIAxes_Image, 'X')
            ylabel(app.UIAxes_Image, 'Y')
            app.Panel_Scene = uipanel(app.MetalDetectionUIFigure);
            app.Panel_Scene.Title = '场景配置';
            app.Panel_Model = uipanel(app.Panel_Scene);
            app.Panel_Model.Title = '模型';
            app.Panel_Detector = uipanel(app.Panel_Model);
            app.Panel_Detector.Title = '探测器配置';
            app.AEditFieldLabel = uilabel(app.Panel_Detector);
            app.AEditFieldLabel.Text = '电流/A';
            app.AEditField = uieditfield(app.Panel_Detector, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.AEditField.Value = 20;
            app.mEditFieldLabel = uilabel(app.Panel_Detector);
            app.mEditFieldLabel.Text = '半径/m';
            app.mEditField = uieditfield(app.Panel_Detector, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.mEditField.Value = 0.25;
            app.ButtonGroup = uibuttongroup(app.Panel_Detector);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.ButtonGroup.Title = '类型';
            app.FD = uiradiobutton(app.ButtonGroup);
            app.FD.Text = '频域';
            app.FD.Value = true;
            app.TD = uiradiobutton(app.ButtonGroup);
            app.TD.Text = '时域';
            app.HzEditFieldLabel = uilabel(app.Panel_Detector);
            app.HzEditFieldLabel.Text = '频率/Hz';
            app.HzEditField = uieditfield(app.Panel_Detector, 'numeric');
            app.HzEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.HzEditField.Value = 1000;
            app.Panel_Space = uipanel(app.Panel_Model);
            app.Panel_Space.Title = '空间定义';
            app.xLabel = uilabel(app.Panel_Space);
            app.xLabel.Text = 'x:';
            app.xEditField = uieditfield(app.Panel_Space, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.xEditField.Value = -2;
            app.xEditField_2 = uieditfield(app.Panel_Space, 'numeric');
            app.xEditField_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.xEditField_2.Value = 2;
            app.Label_2 = uilabel(app.Panel_Space);
            app.Label_2.Text = '~';
            app.mLabel = uilabel(app.Panel_Space);
            app.mLabel.Text = 'm';
            app.yEditFieldLabel = uilabel(app.Panel_Space);
            app.yEditFieldLabel.Text = 'y:';
            app.yEditField = uieditfield(app.Panel_Space, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.yEditField.Value = -2;
            app.xEditField_4 = uieditfield(app.Panel_Space, 'numeric');
            app.xEditField_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.xEditField_4.Value = 2;
            app.Label_4 = uilabel(app.Panel_Space);
            app.Label_4.Text = '~';
            app.mLabel_2 = uilabel(app.Panel_Space);
            app.mLabel_2.Text = 'm';
            app.zEditFieldLabel = uilabel(app.Panel_Space);
            app.zEditFieldLabel.Text = 'z:';
            app.zEditField = uieditfield(app.Panel_Space, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.zEditField.Value = -2;
            app.Label_5 = uilabel(app.Panel_Space);
            app.Label_5.Text = '~';
            app.xEditField_5 = uieditfield(app.Panel_Space, 'numeric');
            app.xEditField_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.mLabel_3 = uilabel(app.Panel_Space);
            app.mLabel_3.Text = 'm';
            app.Panel_Metal = uipanel(app.Panel_Model);
            app.Panel_Metal.Title = '目标物体';
            app.smEditFieldLabel = uilabel(app.Panel_Metal);
            app.smEditFieldLabel.Text = '电导率(s/m)';
            app.smEditField = uieditfield(app.Panel_Metal, 'numeric');
            app.smEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.smEditField.Value = 5.17;
            app.Label_6 = uilabel(app.Panel_Metal);
            app.Label_6.Text = '类型';
            app.DropDown = uidropdown(app.Panel_Metal);
            app.DropDown.Items = {'极化率因子', '球体', '圆柱体'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.DropDown.Value = '圆柱体';
            app.xyzmEditFieldLabel = uilabel(app.Panel_Metal);
            app.xyzmEditFieldLabel.Text = '位置/x,y,z(m)';
            app.xyzmEditField = uieditfield(app.Panel_Metal, 'text');
            app.xyzmEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.xyzmEditField.Value = '0 0 -1';
            app.Label_7 = uilabel(app.Panel_Metal);
            app.Label_7.Text = '姿态角';
            app.EditField = uieditfield(app.Panel_Metal, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.EditField.Value = '0 0 0';
            app.EditField_6Label = uilabel(app.Panel_Metal);
            app.EditField_6Label.Text = '相对磁导率';
            app.EditField_6 = uieditfield(app.Panel_Metal, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.EditField_6.Value = 1;
            app.mLabel_4 = uilabel(app.Panel_Metal);
            app.mLabel_4.Text = '半径/m';
            app.mEditField_3 = uieditfield(app.Panel_Metal, 'numeric');
            app.mEditField_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.mEditField_3.Value = 0.2;
            app.mEditField_4Label = uilabel(app.Panel_Metal);
            app.mEditField_4Label.Text = '长度/m';
            app.mEditField_4 = uieditfield(app.Panel_Metal, 'numeric');
            app.mEditField_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.mEditField_4.Value = 0.8;
            app.Panel_Algorithm = uipanel(app.Panel_Scene);
            app.Panel_Algorithm.Title = '算法';
            app.TabGroup = uitabgroup(app.Panel_Algorithm);
            app.Tab_Image = uitab(app.TabGroup);
            app.Tab_Image.Title = '成像定位';
            app.Label_10 = uilabel(app.Tab_Image);
            app.Label_10.Text = '→';
            app.Label_12 = uilabel(app.Tab_Image);
            app.Label_12.Text = '↓';
            app.Panel = uipanel(app.Tab_Image);
            app.Panel.Title = '预处理';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '降噪';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'高斯', '均值', '小波'};
            app.DropDown_2.Value = '高斯';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '成图';
            app.EditField_8 = uieditfield(app.Panel, 'text');
            app.EditField_8.Value = '0';
            app.Panel_2 = uipanel(app.Tab_Image);
            app.Panel_2.Title = '图像分割';
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = '方法';
            app.DropDown_3 = uidropdown(app.Panel_2);
            app.DropDown_3.Items = {'阈值', 'CV-Active', 'Sobel算子'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'CV-Active';
            app.Label_15 = uilabel(app.Panel_2);
            app.Label_15.Text = '阈值/噪声倍数';
            app.EditField_9 = uieditfield(app.Panel_2, 'text');
            app.EditField_9.Value = '10';
            app.Label_16 = uilabel(app.Tab_Image);
            app.Label_16.Text = '→';
            app.Panel_3 = uipanel(app.Tab_Image);
            app.Panel_3.Title = '定位';
            app.Label_17 = uilabel(app.Panel_3);
            app.Label_17.Text = '方法';
            app.DropDown_4 = uidropdown(app.Panel_3);
            app.DropDown_4.Items = {'峰值', '几何不变矩', '霍夫变换'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '霍夫变换';
            app.Label_18 = uilabel(app.Panel_3);
            app.Label_18.Text = '半径范围/像素';
            app.EditField_10 = uieditfield(app.Panel_3, 'text');
            app.EditField_10.Value = '5';
            app.Tab_Inversion = uitab(app.TabGroup);
            app.Tab_Inversion.Title = '反演求参';
            app.Panel_5 = uipanel(app.Tab_Inversion);
            app.Panel_5.Title = '反演模型';
            app.Label_20 = uilabel(app.Panel_5);
            app.Label_20.Text = '模型';
            app.DropDown_5 = uidropdown(app.Panel_5);
            app.DropDown_5.Items = {'单偶极子', 'ONVMS', 'NSMS'};
            app.DropDown_5.Value = '单偶极子';
            app.Label_21 = uilabel(app.Tab_Inversion);
            app.Label_21.Text = '+';
            app.Panel_6 = uipanel(app.Tab_Inversion);
            app.Panel_6.Title = '反演方法';
            app.Label_22 = uilabel(app.Panel_6);
            app.Label_22.Text = '方法';
            app.DropDown_6 = uidropdown(app.Panel_6);
            app.DropDown_6.Items = {'牛顿法', '模拟退火', '遗传变异'};
            app.DropDown_6.Value = '牛顿法';
            app.Label_23 = uilabel(app.Tab_Inversion);
            app.Label_23.Text = '=';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '训练分类';
            app.Panel_Data = uipanel(app.Panel_Scene);
            app.Panel_Data.Title = '数据';
            app.mEditField_5Label = uilabel(app.Panel_Data);
            app.mEditField_5Label.Text = '线间隔/m';
            app.mEditField_5 = uieditfield(app.Panel_Data, 'numeric');
            app.mEditField_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.mEditField_5.Value = 0.1;
            app.mLabel_5 = uilabel(app.Panel_Data);
            app.mLabel_5.Text = '点间隔/m';
            app.mEditField_6 = uieditfield(app.Panel_Data, 'numeric');
            app.mEditField_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.mEditField_6.Value = 0.1;
            app.mLabel_6 = uilabel(app.Panel_Data);
            app.mLabel_6.Text = '扫描高度/m';
            app.mEditField_7 = uieditfield(app.Panel_Data, 'numeric');
            app.mEditField_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.Label_8 = uilabel(app.Panel_Data);
            app.Label_8.Text = '磁场特征';
            app.DropDown_Bxyz = uidropdown(app.Panel_Data);
            app.DropDown_Bxyz.Items = {'总场', 'Bx', 'By', 'Bz'};
            app.DropDown_Bxyz.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.DropDown_Bxyz.Value = '总场';
            app.Label_9 = uilabel(app.Panel_Data);
            app.Label_9.Text = '特征选择';
            app.DropDown_Feature = uidropdown(app.Panel_Data);
            app.DropDown_Feature.Items = {'幅度', '相位', '通道', '时域响应积分'};
            app.DropDown_Feature.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.DropDown_Feature.Value = '幅度';
            app.Button_3 = uibutton(app.Panel_Data, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '生成特征图';
            app.dBEditFieldLabel = uilabel(app.Panel_Data);
            app.dBEditFieldLabel.Text = '信噪比/dB';
            app.dBEditField = uieditfield(app.Panel_Data, 'numeric');
            app.dBEditField.ValueChangedFcn = createCallbackFcn(app, @DropDown_FeatureValueChanged, true);
            app.dBEditField.Value = 20;
            app.Panel_4 = uipanel(app.MetalDetectionUIFigure);
            app.Panel_4.Title = '输出配置';
            app.CheckBox = uicheckbox(app.Panel_4);
            app.CheckBox.Text = '自动保存';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.Panel_4);
            app.CheckBox_2.Text = '自动保存图片';
            app.CheckBox_3 = uicheckbox(app.Panel_4);
            app.CheckBox_3.Text = '原始数据';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.Panel_4);
            app.CheckBox_4.Text = '降噪结果';
            app.CheckBox_5 = uicheckbox(app.Panel_4);
            app.CheckBox_5.Text = '分割结果';
            app.CheckBox_6 = uicheckbox(app.Panel_4);
            app.CheckBox_6.Text = '定位结果';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.Panel_4);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '绘图';
            app.CheckBox_7.Value = true;
            app.Output = uitextarea(app.MetalDetectionUIFigure);
            app.Label_19 = uilabel(app.MetalDetectionUIFigure);
            app.Label_19.Text = '输出';
            app.Button_4 = uibutton(app.MetalDetectionUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '运行';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MetalDetection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetalDetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetalDetectionUIFigure)
        end
    end
end